classdef gui_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControlModeStateTextArea       matlab.ui.control.TextArea
        ControlModeStateTextAreaLabel  matlab.ui.control.Label
        MotorSpeedTextArea             matlab.ui.control.TextArea
        MotorSpeedTextAreaLabel        matlab.ui.control.Label
        MotorStateTextArea             matlab.ui.control.TextArea
        MotorStateTextAreaLabel        matlab.ui.control.Label
        MotorSpeedKnob                 matlab.ui.control.Knob
        MotorSpeedKnobLabel            matlab.ui.control.Label
        EmergencyShutdownButton        matlab.ui.control.Button
        SystemOnButton                 matlab.ui.control.Button
        ControlModeButton              matlab.ui.control.Button
        ReverseMotorButton             matlab.ui.control.Button
        ForwardMotorButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduinoObj                    % Serial port object
        power_button logical           % System ON/OFF
        reverse_button logical         % Reverse direction flag
        control_mode_button logical   % Control mode flag
        emergency_shutdown logical     % Emergency shutdown flag  
        pause_value = 0; % in sec
        port = "COM7";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.arduinoObj = serialport(app.port, 9600); % select the port
                pause(5);
                if(app.arduinoObj.NumBytesAvailable > 0)
                    response = readline(app.arduinoObj);
                    disp("Starting Application");
                    disp("Arduino replied: " + response);
                end
            catch
                uialert(app.UIFigure, 'Unable to connect to Arduino. Check COM port.', 'Connection Error');
                return;
            end

            app.power_button = false;
            app.reverse_button = false;
            app.control_mode_button = false;
            app.emergency_shutdown = false;

            app.MotorStateTextArea.Value = "System Off - Forward Rotating";
            app.ControlModeStateTextArea.Value = "Open Loop Control";
            app.MotorSpeedTextArea.Value = "";
        end

        % Button pushed function: SystemOnButton
        function SystemOnButtonPushed(app, event)
            app.power_button = true;
            write(app.arduinoObj, "POWER_ON", "string");
            disp("Sent to Arduino: POWER_ON");

            pause(app.pause_value);
            if(app.arduinoObj.NumBytesAvailable > 0)
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
            end

            app.MotorStateTextArea.Value = "System On";
        end

        % Button pushed function: ForwardMotorButton
        function ForwardMotorButtonPushed(app, event)
            app.reverse_button = false;
            write(app.arduinoObj, "FORWARD", "string");
            disp("Sent to Arduino: FORWARD");
            
            pause(app.pause_value);
            if(app.arduinoObj.NumBytesAvailable > 0)
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
            end

            app.MotorStateTextArea.Value = "Forward Rotating";
        end

        % Button pushed function: ReverseMotorButton
        function ReverseMotorButtonPushed(app, event)
            app.reverse_button = true;
            write(app.arduinoObj, "REVERSE", "string");
            disp("Sent to Arduino: REVERSE");
            
            pause(app.pause_value);
            if(app.arduinoObj.NumBytesAvailable > 0)
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
            end

            app.MotorStateTextArea.Value = "Reverse Rotating";
        end

        % Button pushed function: ControlModeButton
        function ControlModeButtonPushed(app, event)
            app.control_mode_button = ~app.control_mode_button;
            if app.control_mode_button
                write(app.arduinoObj, "CLOSED_LOOP", "string");
                disp("Sent to Arduino: CLOSED_LOOP");
                app.ControlModeStateTextArea.Value = "Closed Loop Control";
            else
                write(app.arduinoObj, "OPEN_LOOP", "string");
                disp("Sent to Arduino: OPEN_LOOP");
                app.ControlModeStateTextArea.Value = "Open Loop Control";
            end

            pause(app.pause_value);
             if(app.arduinoObj.NumBytesAvailable > 0)
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
            end
        end

        % Button pushed function: EmergencyShutdownButton
        function EmergencyShutdownButtonPushed(app, event)
            app.emergency_shutdown = true;
            write(app.arduinoObj, "EMER_SHUTDOWN", "string");
            disp("Sent to Arduino: EMER_SHUTDOWN");
            
            pause(app.pause_value);
            if(app.arduinoObj.NumBytesAvailable > 0)
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
            end

            app.MotorStateTextArea.Value = "Emergency Shutdown!";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if isvalid(app.arduinoObj)
                    write(app.arduinoObj, "SHUTDOWN", "string");
                    clear app.arduinoObj
                end
            catch
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ForwardMotorButton = uibutton(app.UIFigure, 'push');
            app.ForwardMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardMotorButtonPushed, true);
            app.ForwardMotorButton.Tooltip = {'Start the motor in the forward direction'};
            app.ForwardMotorButton.Text = 'Forward Motor';
            app.ReverseMotorButton = uibutton(app.UIFigure, 'push');
            app.ReverseMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseMotorButtonPushed, true);
            app.ReverseMotorButton.Tooltip = {'Rotate the motor in the reverse direction'};
            app.ReverseMotorButton.Text = 'Reverse Motor';
            app.ControlModeButton = uibutton(app.UIFigure, 'push');
            app.ControlModeButton.ButtonPushedFcn = createCallbackFcn(app, @ControlModeButtonPushed, true);
            app.ControlModeButton.Tooltip = {'Switch Between Open Loop Control and Close Loop Control'};
            app.ControlModeButton.Text = 'Control Mode Button';
            app.SystemOnButton = uibutton(app.UIFigure, 'push');
            app.SystemOnButton.ButtonPushedFcn = createCallbackFcn(app, @SystemOnButtonPushed, true);
            app.SystemOnButton.Tooltip = {'Turn the System On'};
            app.SystemOnButton.Text = 'System On';
            app.EmergencyShutdownButton = uibutton(app.UIFigure, 'push');
            app.EmergencyShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyShutdownButtonPushed, true);
            app.EmergencyShutdownButton.Tooltip = {'Emergency Shutdown'};
            app.EmergencyShutdownButton.Text = 'Emergency Shutdown';
            app.MotorSpeedKnobLabel = uilabel(app.UIFigure);
            app.MotorSpeedKnobLabel.Text = 'Motor Speed';
            app.MotorSpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.MotorSpeedKnob.Tooltip = {'Control the Speed of the Motor'};
            app.MotorStateTextAreaLabel = uilabel(app.UIFigure);
            app.MotorStateTextAreaLabel.Text = 'Motor State';
            app.MotorStateTextArea = uitextarea(app.UIFigure);
            app.MotorSpeedTextAreaLabel = uilabel(app.UIFigure);
            app.MotorSpeedTextAreaLabel.Text = 'Motor Speed';
            app.MotorSpeedTextArea = uitextarea(app.UIFigure);
            app.ControlModeStateTextAreaLabel = uilabel(app.UIFigure);
            app.ControlModeStateTextAreaLabel.Text = 'Control Mode State';
            app.ControlModeStateTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end